classdef InCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ControlPanel                    matlab.ui.container.Panel
        BeginBubbleDetectionButton      matlab.ui.control.Button
        BeginBubbleAnalysisButton       matlab.ui.control.Button
        ControlPanelTabs                matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        LoadNewVideoButton              matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        PathEditFieldLabel              matlab.ui.control.Label
        VideoPath                       matlab.ui.control.EditField
        LoadPath                        matlab.ui.control.EditField
        SavePath                        matlab.ui.control.EditField
        DetectionTab                    matlab.ui.container.Tab
        IgnoreFirstFrameCheckBox        matlab.ui.control.CheckBox
        MultibubbleAnalysisCheckBox     matlab.ui.control.CheckBox
        FramePreprocessingMethodButtonGroup  matlab.ui.container.ButtonGroup
        SharpenButton                   matlab.ui.control.RadioButton
        SoftenButton                    matlab.ui.control.RadioButton
        FilterStrengthSpinnerLabel      matlab.ui.control.Label
        FilterStrengthSpinner           matlab.ui.control.Spinner
        PreProcessFramesCheckBox        matlab.ui.control.CheckBox
        RunDetectionBothWaysCheckBox    matlab.ui.control.CheckBox
        FrameIgnoreWarningCheckBox      matlab.ui.control.CheckBox
        AnalysisTab                     matlab.ui.container.Tab
        MinArcLengthSpinnerLabel        matlab.ui.control.Label
        MinArcLengthSpinner             matlab.ui.control.Spinner
        FitFourierSeriestoPointsCheckBox  matlab.ui.control.CheckBox
        NumberofTermsinFitButtonGroup   matlab.ui.container.ButtonGroup
        FixedButton                     matlab.ui.control.RadioButton
        ArcLengthdependentButton        matlab.ui.control.RadioButton
        MaxNumberofTermsEditFieldLabel  matlab.ui.control.Label
        MaxNumberofTermsEditField       matlab.ui.control.NumericEditField
        TermstoPlotEditFieldLabel       matlab.ui.control.Label
        TermstoPlotEditField            matlab.ui.control.NumericEditField
        CameraSettingsTab               matlab.ui.container.Tab
        MicronPixelEditFieldLabel       matlab.ui.control.Label
        MicronPixelEditField            matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        PlotAxesSettingsButtonGroup     matlab.ui.container.ButtonGroup
        PxFrameButton                   matlab.ui.control.RadioButton
        MicronSecondsButton             matlab.ui.control.RadioButton
        UpdateButton                    matlab.ui.control.Button
        IMRTab                          matlab.ui.container.Tab
        ExportDataDirectlytoIMRButton   matlab.ui.control.Button
        NumberFourierTermstoExportEditFieldLabel  matlab.ui.control.Label
        NumberFourierTermstoExportEditField  matlab.ui.control.NumericEditField
        SaveIMRDatatoFileButton         matlab.ui.control.Button
        CompileMultipleIMRDataSetsButton  matlab.ui.control.Button
        IncludeCurrentAnalysisCheckBox  matlab.ui.control.CheckBox
        ThemesTab                       matlab.ui.container.Tab
        OptionsButtonGroup              matlab.ui.container.ButtonGroup
        DefaultButton                   matlab.ui.control.RadioButton
        NightButton                     matlab.ui.control.RadioButton
        DarkButton                      matlab.ui.control.RadioButton
        ApplyButton                     matlab.ui.control.Button
        ArmyButton                      matlab.ui.control.RadioButton
        LeatherButton                   matlab.ui.control.RadioButton
        WavesButton                     matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        RePlotFourierDataButton         matlab.ui.control.Button
        ExporttoExcelButton             matlab.ui.control.Button
        MaskOverlayPreviewPanel         matlab.ui.container.Panel
        MainViewerPanel                 matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ViewerTab                       matlab.ui.container.Tab
        MainPlot                        matlab.ui.control.UIAxes
        PreviousFrameButton             matlab.ui.control.Button
        NextFrameButton                 matlab.ui.control.Button
        AreaLabel                       matlab.ui.control.Label
        PerimeterLabel                  matlab.ui.control.Label
        AverageRadiusLabel              matlab.ui.control.Label
        CentroidLabel                   matlab.ui.control.Label
        FrameNumberSpinnerLabel         matlab.ui.control.Label
        FrameNumberSpinner              matlab.ui.control.Spinner
        EvolutionOverlayTab             matlab.ui.container.Tab
        EvolutionPlot                   matlab.ui.control.UIAxes
        ColorMap                        matlab.ui.control.Image
        Frame1Label                     matlab.ui.control.Label
        Frame180Label                   matlab.ui.control.Label
        AnalysisResultsPanel            matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        AverageRadiusPanel              matlab.ui.container.Panel
        RadiusPlot                      matlab.ui.control.UIAxes
        SurfaceAreaandVolumePanel       matlab.ui.container.Panel
        ThreeDimensionalPlot            matlab.ui.control.UIAxes
        AreaandPerimeterPanel           matlab.ui.container.Panel
        TwoDimensionalPlot              matlab.ui.control.UIAxes
        CentroidTab                     matlab.ui.container.Tab
        CentroidPlot                    matlab.ui.control.UIAxes
        FourierFitDataTab               matlab.ui.container.Tab
        AsphericityPlot                 matlab.ui.control.UIAxes
        FourierColorMap                 matlab.ui.control.Image
        ndTermLabel                     matlab.ui.control.Label
        LastTermLabel                   matlab.ui.control.Label
        FourierDecompositionTab         matlab.ui.container.Tab
        DecompositionSettingsPanel      matlab.ui.container.Panel
        TargetFrameEditFieldLabel       matlab.ui.control.Label
        TargetFrameEditField            matlab.ui.control.NumericEditField
        TermstoDecomposeEditFieldLabel  matlab.ui.control.Label
        TermstoDecomposeEditField       matlab.ui.control.NumericEditField
        SortOrderButtonGroup            matlab.ui.container.ButtonGroup
        DescendingButton                matlab.ui.control.RadioButton
        AscendingButton                 matlab.ui.control.RadioButton
        MaintainColormapCheckBox        matlab.ui.control.CheckBox
        DecomposeButton                 matlab.ui.control.Button
        DecomposedPlotsPanel            matlab.ui.container.Panel
    end

    
    properties (Access = public)
        %Main data
        frames
        mask
        maskInformation
        videoPath
        ignoreFrames
        currentFrame
        radius
        area
        perimeter
        surfaceArea
        centroid
        volume
        
        %User Input valuse
        preProcessingMethod
        preProcessingStrength
        minArcLength
        numberOfTermsinFitType
        maxNumberOfTerms
        termsToPlot
        numFrames
        convertedPlotSet
        frameInterval
        mpxRatio
        fps
        plotAxesSetting
        numberFourierTermsToExport
        includeCurrentAnalysisinExport
        theme
    end
    
    methods (Access = private)
        
        function rickRoll(~)
            figureui = uifigure("WindowState", "maximized");
            figureui.Name = "Why did you press that button";
            drawnow;
            pause('on');
            pause(0.02);
            position = figureui.Position;
            html = uihtml(figureui, "Position", [0, 0, position(3), position(4)]);
            html.HTMLSource = fullfile(pwd, '/main/test.htm');
        end
        
        function resetFunction(app)
            % Clear main variables
            clear app.frames;
            clear app.mask;
            clear app.maskInformation;
            clear app.videoPath;
            clear app.ignoreFrames;
            app.currentFrame = 1;
            clear app.radius;
            clear app.area;
            clear app.perimeter;
            clear app.surfaceArea;
            clear app.centroid;
            clear app.volume
            clear app.numFrames;
            clear app.convertedPlotSet;
            
            %Clear textboxes
            app.LoadPath.Value = "";
            app.SavePath.Value = "";
            
            %Clear plots and graphs
            cla(app.MainPlot);
            cla(app.EvolutionPlot);
            cla(app.RadiusPlot);
            cla(app.TwoDimensionalPlot);
            cla(app.ThreeDimensionalPlot);
            cla(app.CentroidPlot);
            yyaxis(app.AsphericityPlot, 'left');
            cla(app.AsphericityPlot);
            yyaxis(app.AsphericityPlot, 'right');
            cla(app.AsphericityPlot);
            
            %Reset Fourier Decomp Tab
            app.TargetFrameEditField.Value = 1;
            app.TermstoDecomposeEditField.Value = 8;
            app.DescendingButton.Value = 1;
            app.MaintainColormapCheckBox.Value = 1;
            delete(app.DecomposedPlotsPanel.Children);
            delete(app.MaskOverlayPreviewPanel.Children);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('main\');
            addpath('logs\');
            addpath('themes\');
            addpath('images\');
            yyaxis(app.TwoDimensionalPlot, 'right');
            app.TwoDimensionalPlot.YLabel.String = 'Perimeter (pixels)';
            yyaxis(app.TwoDimensionalPlot, 'left');
            app.TwoDimensionalPlot.YColor = [0.00,0.45,0.74];
            yyaxis(app.ThreeDimensionalPlot, 'right');
            app.ThreeDimensionalPlot.YLabel.String = 'Volume (Cubic Pixels)';
            yyaxis(app.ThreeDimensionalPlot, 'left');
            app.ThreeDimensionalPlot.YColor = [0.00,0.45,0.74];
            drawnow;
            app.numberFourierTermsToExport = app.NumberFourierTermstoExportEditField.Value;
            app.currentFrame = 1;
%             checkTheme(app);
%             setTheme(app, "night");
%             figureappearance.setTheme(app, "default");
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadNewVideoButton
        function LoadNewVideoButtonPushed(app, event)
            resetFunction(app);
            [app.frames, vidPath, vidFile] = incaio.loadFrames();
            figure(app.UIFigure);
            if any(vidFile == 0) || any(vidPath == 0)
                fullpath = append(vidPath, vidFile);
                app.VideoPath.Value = fullpath;
                app.videoPath = fullpath;
                [~, ~, app.numFrames] = size(app.frames);
            end
            uialert(app.UIFigure, 'Video loaded!', 'Success', 'Icon',"success", "Modal", true);
        end

        % Button pushed function: BeginBubbleDetectionButton
        function BeginBubbleDetectionButtonPushed(app, event)
            clear app.ignoreFrames;
            clear app.mask;
            if app.IgnoreFirstFrameCheckBox.Value
                app.ignoreFrames(length(app.ignoreFrames) + 1) = 1;
            end
            if app.PreProcessFramesCheckBox.Value
                detectionFrames = bubbleAnalysis.preprocessFrames(app, app.frames);
            else
                detectionFrames = app.frames;
            end
            if app.RunDetectionBothWaysCheckBox.Value
                forwardMask = bubbleAnalysis.maskGenForward(app, detectionFrames);
                reverseMask = bubbleAnalysis.maskGenReverse(app, detectionFrames);
                f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Comparing masks...", "Indeterminate","on");
                app.mask = bubbleAnalysis.compareMasks(forwardMask, reverseMask);
                close(f)
            else
                app.mask = bubbleAnalysis.maskGenForward(app, detectionFrames);
            end
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Generating frame previews...", "Indeterminate","on");
            generatePreviews(app, app.frames, app.mask);
            close(f);
            uialert(app.UIFigure, "Bubble detection complete! Please review frames before proceeding. Click on the image to add it to the list of frames to ignore", 'Message', 'Icon', 'info');
        end

        % Button pushed function: BeginBubbleAnalysisButton
        function BeginBubbleAnalysisButtonPushed(app, event)
            clear app.maskInformation
            app.maskInformation = bubbleAnalysis.bubbleTrack(app, app.mask, app.MinArcLengthSpinner.Value, 'horizontal',...
                app.FitFourierSeriestoPointsCheckBox.Value, app.MaxNumberofTermsEditField.Value, app.FixedButton.Value, app.ignoreFrames);
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Configuring for plotting...", "Indeterminate","on");
            [app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid] = plotting.generatePlotData(app);
            plotting.displayCurrentFrame(app);
            app.convertedPlotSet = plotting.convertUnits(app);
            if app.PxFrameButton.Value
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
                plotting.dispEvolution(app);
                plotting.plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
                plotting.plotConvertedFourier(app);
                plotting.dispEvolution(app);
            end
            drawnow;
            pause(0.01);
            close(f);
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.currentFrame == app.numFrames
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 1;
            end
            plotting.displayCurrentFrame(app);
            if app.PxFrameButton.Value
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
            else
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
            end
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            if app.currentFrame == 1
                app.currentFrame = app.numFrames;
            else
                app.currentFrame = app.currentFrame - 1;
            end
            plotting.displayCurrentFrame(app);
            if app.PxFrameButton.Value
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
            else
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            w = uiprogressdlg(app.UIFigure, "Title", 'Please wait', 'Message', 'Loading data...', 'Indeterminate',"on");
            [app, fullLoadPath] = incaio.readFromFile(app);
            figure(app.UIFigure);
            generatePreviews(app, app.frames, app.mask)
            plotting.displayCurrentFrame(app);
            plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
            sizes = zeros(1, app.numFrames);
            for i = 1:app.numFrames
                sizes(i) = length(app.maskInformation(i).xData);
            end
            maxSize = max(sizes);
            if maxSize > 1
                app.FitFourierSeriestoPointsCheckBox.Value = 1;
            end
            if app.PxFrameButton.Value
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
                plotting.dispEvolution(app);
                plotting.plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
                plotting.plotConvertedFourier(app);
                plotting.dispEvolution(app);
            end
            if ~isempty(fullLoadPath)
                app.LoadPath.Value = fullLoadPath;
            end
            FPSEditFieldValueChanged(app, event);
            drawnow;
            pause(0.01);
            close(w);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure, "Title", "Please wait", 'Message', 'Saving...', "Indeterminate", "on");
            savePath = incaio.writeToFile(app);
            if ~isempty(savePath)
                app.SavePath.Value = savePath;
            end
            close(f);
            uialert(app.UIFigure, "Save complete!", 'Message', 'Icon', "success");
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Changing theme...", "Indeterminate","on");
            figureappearance.checkTheme(app);
            close(f);
        end

        % Button pushed function: RePlotFourierDataButton
        function RePlotFourierDataButtonPushed(app, event)
            if app.PxFrameButton.Value
                plotting.plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotting.plotConvertedFourier(app);
            end
        end

        % Button pushed function: DecomposeButton
        function DecomposeButtonPushed(app, event)
            if app.DescendingButton.Value == true
                sortOrder = "descending";
            else
                sortOrder = "ascending";
            end
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Decomposing", "Indeterminate","on");
            decompPlots = plotting.fourierDecomposition(app.maskInformation, app.TargetFrameEditField.Value, app.TermstoDecomposeEditField.Value, sortOrder);
            f.Title = "Plotting...";
            plotting.plotFourierDecomp(app, decompPlots);
            close(f);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            disp(key);
            switch key
                case 'f1'
                    LoadNewVideoButtonPushed(app, event);
                case 'f2'
                    LoadDataButtonPushed(app, event);
                case 'f3'
                    SaveDataButtonPushed(app, event);
                case 'f4'
                    BeginBubbleDetectionButtonPushed(app, event);
                case 'f5'
                    RePlotFourierDataButtonPushed(app, event);
                case 'f6'
                    BeginBubbleAnalysisButtonPushed(app, event);
                case 'f7'
                    ExporttoExcelButtonPushed(app, event)
                case 'f8'
                    if app.IgnoreFirstFrameCheckBox.Value == 1
                        app.IgnoreFirstFrameCheckBox.Value = 0;
                    else
                        app.IgnoreFirstFrameCheckBox.Value = 1;
                    end
                case 'f9'
                    if app.MultibubbleAnalysisCheckBox.Value == 1
                        app.MultibubbleAnalysisCheckBox.Value = 0;
                    else
                        app.MultibubbleAnalysisCheckBox.Value = 1;
                    end
                case 'f10'
                    if app.RunDetectionBothWaysCheckBox.Value == 1
                        app.RunDetectionBothWaysCheckBox.Value = 0;
                    else
                        app.RunDetectionBothWaysCheckBox.Value = 1;
                    end
                case 'f11'
                    if app.PreProcessFramesCheckBox.Value == 1
                        app.PreProcessFramesCheckBox.Value = 0;
                    else
                        app.PreProcessFramesCheckBox.Value = 1;
                    end
                case 'f12'
                    if app.FitFourierSeriestoPointsCheckBox.Value == 1
                        app.FitFourierSeriestoPointsCheckBox.Value = 0;
                    else
                        app.FitFourierSeriestoPointsCheckBox.Value = 1;
                    end
                case 'backslash'
                    launchLogViewer();
                case 'home'
                    rickRoll(app);
                case 'rightarrow'
                    NextFrameButtonPushed(app, event);
                case 'leftarrow'
                    PreviousFrameButtonPushed(app, event);
            end
        end

        % Value changed function: FrameNumberSpinner
        function FrameNumberSpinnerValueChanged(app, event)
            value = app.FrameNumberSpinner.Value;
            app.currentFrame = value;
            plotting.displayCurrentFrame(app);
            if app.PxFrameButton.Value
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
            else
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
            end
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
            incaio.exportToExcel(app, app.ignoreFrames);
            uialert(app.UIFigure, "Export complete!", 'Message', 'Icon', "success");
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
            %Analysis Panel Resizing
            app.AnalysisResultsPanel.Position = [10, 5, 620./1700.*position(3), position(4) - 10];
            panelPos = app.AnalysisResultsPanel.Position;
            app.TabGroup.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            tabPos = app.TabGroup.Position;
            tabWidth = tabPos(3);
            app.CentroidPlot.Position = [0, 0, tabPos(3), tabPos(4) - 30];
            app.ndTermLabel.Position = [15, 15, 66, 22];
            app.FourierColorMap.Position = [15, 50, tabWidth - 30, 30];
            app.LastTermLabel.Position = [tabWidth - 15 - 67, 15, 67, 22];
            app.AsphericityPlot.Position = [0, 100, tabWidth - 10, tabPos(4) - 130];
            app.DecomposedPlotsPanel.Position = [0, 0, tabWidth, tabPos(4) - 130];
            app.DecompositionSettingsPanel.Position = [0, tabPos(4) - 120, tabWidth, (tabPos(4) - 25) - 10 - (tabPos(4) - 130)];
            app.SurfaceAreaandVolumePanel.Position = [0, 0, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.AreaandPerimeterPanel.Position = [0, tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.AverageRadiusPanel.Position = [0, 2.*tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            panelPos = app.AreaandPerimeterPanel.Position;
            app.RadiusPlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.TwoDimensionalPlot.Position = [0, 0, panelPos(3) - 10, panelPos(4) - 20];
            app.ThreeDimensionalPlot.Position = [0, 0, panelPos(3) - 10, panelPos(4) - 20];
            
            %Mask Overlay Panel Resizing
            app.MaskOverlayPreviewPanel.Position = [(620./1700.*position(3)) + 20, 5, 390./1700.*position(3), position(4) - 305];
            
            %Control Panel Resizing
            app.ControlPanel.Position = [(620./1700.*position(3)) + 20, position(4) - 295, 390./1700.*position(3), 290];
            app.BeginBubbleAnalysisButton.Position = [15, 3, 147, 25];
            app.BeginBubbleDetectionButton.Position = [15, 43, 147, 25];
            app.ExporttoExcelButton.Position = [(390./1700.*position(3)) - 147 - 15, 3, 147, 25];
            app.RePlotFourierDataButton.Position = [(390./1700.*position(3)) - 147 - 15, 43, 147, 25];
            
            %Main Viewer Panel Resizing
            app.MainViewerPanel.Position = [(620./1700.*position(3)) + (390./1700.*position(3)) + 30, 5, (position(3) - 40) - (390./1700.*position(3)) - (620./1700.*position(3)), position(4) - 10];
            panelPos = app.MainViewerPanel.Position;
            app.TabGroup2.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.MainPlot.Position = [0, panelPos(4)  - (panelPos(3).*(530./648)) - 45, panelPos(3), panelPos(3).*(530./648)];
            app.EvolutionPlot.Position = [0, panelPos(4)  - (panelPos(3).*(530./648)) - 45, panelPos(3), panelPos(3).*(530./648)];
            app.Frame1Label.Position = [15, 15, 58, 22];
            app.ColorMap.Position = [15, 45, panelPos(3) - 30, 30];
            app.Frame180Label.Position = [panelPos(3) - 15 - 74, 15, 74, 22];
            app.NextFrameButton.Position = [panelPos(3) - 110,4,100,28];
            app.FrameNumberSpinner.Position = [346./648.*panelPos(3),10,100,22];
            app.FrameNumberSpinnerLabel.Position = [227./648.*panelPos(3),10,104,22];
            app.AverageRadiusLabel.Position = [panelPos(3) - 226,91,216,22];
            app.CentroidLabel.Position = [panelPos(3) - 226, 52,216,22];
            
            %Control Panel Components Resizig
            panelPos = app.IMRTab.Position;
            app.IncludeCurrentAnalysisCheckBox.Position = [10, 5, 194, 22];
            app.CompileMultipleIMRDataSetsButton.Position = [10, 35, 194, 25];
            app.SaveIMRDatatoFileButton.Position = [10, 65, 194, 25];
            app.ExportDataDirectlytoIMRButton.Position = [10, 95, 194, 25];
            %app.NumberFourierTermstoExportLabel.Position = [10, panelPos(4) - 27, 187, 22];
            app.NumberFourierTermstoExportEditField.Position = [212, panelPos(4)-26, 100, 22];
            drawnow;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Updating...", "Indeterminate","on");
            app.convertedPlotSet = plotting.convertUnits(app);
            if ~isempty(app.radius)
                if app.MicronSecondsButton.Value
                    plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
                    plotting.plotConvertedFourier(app);
                    plotting.changeAxesTitles(app, 'microns');
                elseif app.PxFrameButton.Value
                    plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
                    plotting.plotFourier(app);
                    plotting.changeAxesTitles(app, 'pixels');
                end
            end
            if ~isempty(app.numFrames)
                app.frameInterval = 1./app.FPSEditField.Value;
            end
            close(f);
        end

        % Selection changed function: PlotAxesSettingsButtonGroup
        function PlotAxesSettingsButtonGroupSelectionChanged(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Converting...", "Indeterminate","on");
            selectedButton = app.PlotAxesSettingsButtonGroup.SelectedObject;
            app.convertedPlotSet = plotting.convertUnits(app);
            if selectedButton == app.PxFrameButton
                plotting.plotData(app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid, ...
                    app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot, app.numFrames, app.currentFrame);
                plotting.plotFourier(app);
                plotting.changeAxesTitles(app, 'pixels');
            else
                plotting.plotConvertedData(app.convertedPlotSet, app.RadiusPlot, app.TwoDimensionalPlot, app.ThreeDimensionalPlot, app.CentroidPlot,...
                    app.currentFrame, app.numFrames);
                plotting.plotConvertedFourier(app);
                plotting.changeAxesTitles(app, 'microns');
            end
            close(f);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ExportDataDirectlytoIMRButton
        function ExportDataDirectlytoIMRButtonPushed(app, event)
            data = incaio.configureDataForIMR(app.frames, app.mask, app.numFrames, app.ignoreFrames, app.maskInformation, app.convertedPlotSet, app.numberFourierTermsToExport);
            IMR(data);
        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            value = app.FPSEditField.Value;
            app.frameInterval = 1./value;
        end

        % Button pushed function: SaveIMRDatatoFileButton
        function SaveIMRDatatoFileButtonPushed(app, event)
            data = configureDataForIMR(app.frames, app.mask, app.numFrames, app.ignoreFrames, app.maskInformation, app.convertedPlotSet, app.numberFourierTermsToExport);
%             f = uiprogressdlg(app.UIFigure, "Title", "Please wait", 'Message', 'Saving...', "Indeterminate", "on");
%             savePath = writeToFile(app);
%             if ~isempty(savePath)
%                 app.SavePath.Value = savePath;
%             end
%             close(f);
%             uialert(app.UIFigure, "Save complete!", 'Message', 'Icon', "success");
        end

        % Value changed function: 
        % NumberFourierTermstoExportEditField
        function NumberFourierTermstoExportEditFieldValueChanged(app, event)
            value = app.NumberFourierTermstoExportEditField.Value;
            app.numberFourierTermsToExport = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'InCA';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.BeginBubbleDetectionButton = uibutton(app.ControlPanel, 'push');
            app.BeginBubbleDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginBubbleDetectionButtonPushed, true);
            app.BeginBubbleDetectionButton.Tooltip = {'F4'};
            app.BeginBubbleDetectionButton.Text = 'Begin Bubble Detection';
            app.BeginBubbleAnalysisButton = uibutton(app.ControlPanel, 'push');
            app.BeginBubbleAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BeginBubbleAnalysisButtonPushed, true);
            app.BeginBubbleAnalysisButton.Tooltip = {'F6'};
            app.BeginBubbleAnalysisButton.Text = 'Begin Bubble Analysis';
            app.ControlPanelTabs = uitabgroup(app.ControlPanel);
            app.FileTab = uitab(app.ControlPanelTabs);
            app.FileTab.Title = 'File';
            app.LoadNewVideoButton = uibutton(app.FileTab, 'push');
            app.LoadNewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewVideoButtonPushed, true);
            app.LoadNewVideoButton.Tooltip = {'Load a new video for analysis. '; ''; 'Keyboard shortcut: F1'};
            app.LoadNewVideoButton.Text = 'Load New Video';
            app.SaveDataButton = uibutton(app.FileTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save the current analysis for later viewing. '; ''; 'Keyboard shortcut: F3'};
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.FileTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Load previously analyzed data for viewing. '; ''; 'Keyboard shortcut: F2'};
            app.LoadDataButton.Text = 'Load Data';
            app.PathEditFieldLabel = uilabel(app.FileTab);
            app.PathEditFieldLabel.Text = 'Path: ';
            app.VideoPath = uieditfield(app.FileTab, 'text');
            app.VideoPath.Editable = 'off';
            app.LoadPath = uieditfield(app.FileTab, 'text');
            app.LoadPath.Editable = 'off';
            app.SavePath = uieditfield(app.FileTab, 'text');
            app.SavePath.Editable = 'off';
            app.DetectionTab = uitab(app.ControlPanelTabs);
            app.DetectionTab.Title = 'Detection';
            app.IgnoreFirstFrameCheckBox = uicheckbox(app.DetectionTab);
            app.IgnoreFirstFrameCheckBox.Tooltip = {'Ignore the first frame of the video during the bubble detection and subsequent analysis.'; ''; 'Keyboard shortcut: F8'};
            app.IgnoreFirstFrameCheckBox.Text = 'Ignore First Frame';
            app.IgnoreFirstFrameCheckBox.Value = true;
            app.MultibubbleAnalysisCheckBox = uicheckbox(app.DetectionTab);
            app.MultibubbleAnalysisCheckBox.Tooltip = {'Expect multiple bubbles for each frame in the video.'; ''; 'Keyboard shortcut: F9'};
            app.MultibubbleAnalysisCheckBox.Text = 'Multibubble Analysis';
            app.FramePreprocessingMethodButtonGroup = uibuttongroup(app.DetectionTab);
            app.FramePreprocessingMethodButtonGroup.Title = 'Frame Preprocessing Method';
            app.SharpenButton = uiradiobutton(app.FramePreprocessingMethodButtonGroup);
            app.SharpenButton.Text = 'Sharpen';
            app.SharpenButton.Value = true;
            app.SoftenButton = uiradiobutton(app.FramePreprocessingMethodButtonGroup);
            app.SoftenButton.Text = 'Soften';
            app.FilterStrengthSpinnerLabel = uilabel(app.DetectionTab);
            app.FilterStrengthSpinnerLabel.Text = 'Filter Strength:';
            app.FilterStrengthSpinner = uispinner(app.DetectionTab);
            app.FilterStrengthSpinner.Limits = [1 Inf];
            app.FilterStrengthSpinner.Value = 1;
            app.PreProcessFramesCheckBox = uicheckbox(app.DetectionTab);
            app.PreProcessFramesCheckBox.Tooltip = {'Sharpen or soften edges in the frames before detection occurs.'; ''; 'Keyboard shortcut: F11'};
            app.PreProcessFramesCheckBox.Text = 'Pre-Process Frames';
            app.PreProcessFramesCheckBox.Value = true;
            app.RunDetectionBothWaysCheckBox = uicheckbox(app.DetectionTab);
            app.RunDetectionBothWaysCheckBox.Tooltip = {'Run the detection alrogithm forwards in time and backwards in time and compare the resulting masks. If disabled the analysis only runs forwards. '; ''; 'Keyboard shortcut: F10'};
            app.RunDetectionBothWaysCheckBox.Text = 'Run Detection Both Ways';
            app.FrameIgnoreWarningCheckBox = uicheckbox(app.DetectionTab);
            app.FrameIgnoreWarningCheckBox.Tooltip = {'Toggle whether or not the modal dialog box shows when selecting or de-selecting frames to ignore during bubble analysis. '; ''; 'Keyboard shortcut: NONE'};
            app.FrameIgnoreWarningCheckBox.Text = 'Frame Ignore Warning';
            app.FrameIgnoreWarningCheckBox.Value = true;
            app.AnalysisTab = uitab(app.ControlPanelTabs);
            app.AnalysisTab.Title = 'Analysis';
            app.MinArcLengthSpinnerLabel = uilabel(app.AnalysisTab);
            app.MinArcLengthSpinnerLabel.Text = 'Min Arc Length';
            app.MinArcLengthSpinner = uispinner(app.AnalysisTab);
            app.MinArcLengthSpinner.Limits = [0 Inf];
            app.MinArcLengthSpinner.Value = 3;
            app.FitFourierSeriestoPointsCheckBox = uicheckbox(app.AnalysisTab);
            app.FitFourierSeriestoPointsCheckBox.Tooltip = {'Fit a Fourier Series to the X and Y points on the perimeter of the bubble. '; ''; 'Keyboard shortcut: F12'};
            app.FitFourierSeriestoPointsCheckBox.Text = 'Fit Fourier Series to Points';
            app.NumberofTermsinFitButtonGroup = uibuttongroup(app.AnalysisTab);
            app.NumberofTermsinFitButtonGroup.Title = 'Number of Terms in Fit';
            app.FixedButton = uiradiobutton(app.NumberofTermsinFitButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.ArcLengthdependentButton = uiradiobutton(app.NumberofTermsinFitButtonGroup);
            app.ArcLengthdependentButton.Text = 'Arc-Length dependent';
            app.MaxNumberofTermsEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaxNumberofTermsEditFieldLabel.Text = '(Max) Number of Terms: ';
            app.MaxNumberofTermsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaxNumberofTermsEditField.Limits = [0 Inf];
            app.MaxNumberofTermsEditField.Value = 40;
            app.TermstoPlotEditFieldLabel = uilabel(app.AnalysisTab);
            app.TermstoPlotEditFieldLabel.Text = 'Terms to Plot: ';
            app.TermstoPlotEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TermstoPlotEditField.Limits = [0 Inf];
            app.TermstoPlotEditField.Value = 8;
            app.CameraSettingsTab = uitab(app.ControlPanelTabs);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.MicronPixelEditFieldLabel = uilabel(app.CameraSettingsTab);
            app.MicronPixelEditFieldLabel.Text = 'Micron/Pixel';
            app.MicronPixelEditField = uieditfield(app.CameraSettingsTab, 'numeric');
            app.MicronPixelEditField.Limits = [0 Inf];
            app.FPSEditFieldLabel = uilabel(app.CameraSettingsTab);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.CameraSettingsTab, 'numeric');
            app.FPSEditField.Limits = [0 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.PlotAxesSettingsButtonGroup = uibuttongroup(app.CameraSettingsTab);
            app.PlotAxesSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotAxesSettingsButtonGroupSelectionChanged, true);
            app.PlotAxesSettingsButtonGroup.Title = 'Plot Axes Settings';
            app.PxFrameButton = uiradiobutton(app.PlotAxesSettingsButtonGroup);
            app.PxFrameButton.Text = 'Px/Frame';
            app.PxFrameButton.Value = true;
            app.MicronSecondsButton = uiradiobutton(app.PlotAxesSettingsButtonGroup);
            app.MicronSecondsButton.Text = 'Micron/Seconds';
            app.UpdateButton = uibutton(app.CameraSettingsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.IMRTab = uitab(app.ControlPanelTabs);
            app.IMRTab.Title = 'IMR';
            app.ExportDataDirectlytoIMRButton = uibutton(app.IMRTab, 'push');
            app.ExportDataDirectlytoIMRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataDirectlytoIMRButtonPushed, true);
            app.ExportDataDirectlytoIMRButton.Text = 'Export Data Directly to IMR';
            app.NumberFourierTermstoExportEditFieldLabel = uilabel(app.IMRTab);
            app.NumberFourierTermstoExportEditFieldLabel.Text = 'Number Fourier Terms to Export';
            app.NumberFourierTermstoExportEditField = uieditfield(app.IMRTab, 'numeric');
            app.NumberFourierTermstoExportEditField.Limits = [1 Inf];
            app.NumberFourierTermstoExportEditField.ValueChangedFcn = createCallbackFcn(app, @NumberFourierTermstoExportEditFieldValueChanged, true);
            app.NumberFourierTermstoExportEditField.Value = 8;
            app.SaveIMRDatatoFileButton = uibutton(app.IMRTab, 'push');
            app.SaveIMRDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIMRDatatoFileButtonPushed, true);
            app.SaveIMRDatatoFileButton.Text = {'Save IMR Data to File'; ''};
            app.CompileMultipleIMRDataSetsButton = uibutton(app.IMRTab, 'push');
            app.CompileMultipleIMRDataSetsButton.Text = 'Compile Multiple IMR Data Sets';
            app.IncludeCurrentAnalysisCheckBox = uicheckbox(app.IMRTab);
            app.IncludeCurrentAnalysisCheckBox.Text = 'Include Current Analysis';
            app.IncludeCurrentAnalysisCheckBox.Value = true;
            app.ThemesTab = uitab(app.ControlPanelTabs);
            app.ThemesTab.Title = 'Themes';
            app.OptionsButtonGroup = uibuttongroup(app.ThemesTab);
            app.OptionsButtonGroup.Title = 'Dity''s Curated Collection';
            app.DefaultButton = uiradiobutton(app.OptionsButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.NightButton = uiradiobutton(app.OptionsButtonGroup);
            app.NightButton.Text = 'Night';
            app.DarkButton = uiradiobutton(app.OptionsButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.ApplyButton = uibutton(app.OptionsButtonGroup, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ArmyButton = uiradiobutton(app.OptionsButtonGroup);
            app.ArmyButton.Text = 'Army';
            app.LeatherButton = uiradiobutton(app.OptionsButtonGroup);
            app.LeatherButton.Text = 'Leather';
            app.WavesButton = uiradiobutton(app.OptionsButtonGroup);
            app.WavesButton.Text = 'Waves';
            app.CustomButton = uiradiobutton(app.OptionsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.RePlotFourierDataButton = uibutton(app.ControlPanel, 'push');
            app.RePlotFourierDataButton.ButtonPushedFcn = createCallbackFcn(app, @RePlotFourierDataButtonPushed, true);
            app.RePlotFourierDataButton.Tooltip = {'F5'};
            app.RePlotFourierDataButton.Text = 'Re-Plot Fourier Data';
            app.ExporttoExcelButton = uibutton(app.ControlPanel, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Tooltip = {'F7'};
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.MaskOverlayPreviewPanel = uipanel(app.UIFigure);
            app.MaskOverlayPreviewPanel.Title = 'Mask Overlay Preview';
            app.MainViewerPanel = uipanel(app.UIFigure);
            app.MainViewerPanel.Title = 'Main Viewer';
            app.TabGroup2 = uitabgroup(app.MainViewerPanel);
            app.ViewerTab = uitab(app.TabGroup2);
            app.ViewerTab.Title = 'Viewer';
            app.MainPlot = uiaxes(app.ViewerTab);
            title(app.MainPlot, '')
            xlabel(app.MainPlot, '')
            ylabel(app.MainPlot, '')
            app.MainPlot.YDir = 'reverse';
            app.MainPlot.XTick = [];
            app.MainPlot.YTick = [];
            app.PreviousFrameButton = uibutton(app.ViewerTab, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Tooltip = {'Left Arrow Key'};
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.NextFrameButton = uibutton(app.ViewerTab, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Tooltip = {'Right Arrow Key'};
            app.NextFrameButton.Text = 'Next Frame';
            app.AreaLabel = uilabel(app.ViewerTab);
            app.AreaLabel.Text = 'Area: ';
            app.PerimeterLabel = uilabel(app.ViewerTab);
            app.PerimeterLabel.Text = 'Perimeter:';
            app.AverageRadiusLabel = uilabel(app.ViewerTab);
            app.AverageRadiusLabel.Text = 'Average Radius: ';
            app.CentroidLabel = uilabel(app.ViewerTab);
            app.CentroidLabel.Text = 'Centroid: ';
            app.FrameNumberSpinnerLabel = uilabel(app.ViewerTab);
            app.FrameNumberSpinnerLabel.Text = 'Frame Number: ';
            app.FrameNumberSpinner = uispinner(app.ViewerTab);
            app.FrameNumberSpinner.Limits = [1 Inf];
            app.FrameNumberSpinner.RoundFractionalValues = 'on';
            app.FrameNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSpinnerValueChanged, true);
            app.FrameNumberSpinner.Value = 1;
            app.EvolutionOverlayTab = uitab(app.TabGroup2);
            app.EvolutionOverlayTab.Title = 'Evolution Overlay';
            app.EvolutionPlot = uiaxes(app.EvolutionOverlayTab);
            title(app.EvolutionPlot, '')
            xlabel(app.EvolutionPlot, '')
            ylabel(app.EvolutionPlot, '')
            app.EvolutionPlot.YDir = 'reverse';
            app.EvolutionPlot.XTick = [];
            app.EvolutionPlot.YTick = [];
            app.ColorMap = uiimage(app.EvolutionOverlayTab);
            app.Frame1Label = uilabel(app.EvolutionOverlayTab);
            app.Frame1Label.Text = 'Frame 1';
            app.Frame180Label = uilabel(app.EvolutionOverlayTab);
            app.Frame180Label.Text = 'Frame 180';
            app.AnalysisResultsPanel = uipanel(app.UIFigure);
            app.AnalysisResultsPanel.Title = 'Analysis Results';
            app.TabGroup = uitabgroup(app.AnalysisResultsPanel);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.AverageRadiusPanel = uipanel(app.OverviewTab);
            app.AverageRadiusPanel.Title = 'Average Radius';
            app.RadiusPlot = uiaxes(app.AverageRadiusPanel);
            title(app.RadiusPlot, '')
            xlabel(app.RadiusPlot, 'Frame')
            ylabel(app.RadiusPlot, 'Pixels')
            app.SurfaceAreaandVolumePanel = uipanel(app.OverviewTab);
            app.SurfaceAreaandVolumePanel.Title = 'Surface Area and Volume';
            app.ThreeDimensionalPlot = uiaxes(app.SurfaceAreaandVolumePanel);
            title(app.ThreeDimensionalPlot, '')
            xlabel(app.ThreeDimensionalPlot, 'Frame')
            ylabel(app.ThreeDimensionalPlot, 'Surface Area (Square Pixels)')
            app.AreaandPerimeterPanel = uipanel(app.OverviewTab);
            app.AreaandPerimeterPanel.Title = 'Area and Perimeter';
            app.TwoDimensionalPlot = uiaxes(app.AreaandPerimeterPanel);
            title(app.TwoDimensionalPlot, '')
            xlabel(app.TwoDimensionalPlot, 'Frame')
            ylabel(app.TwoDimensionalPlot, 'Area (Square Pixels)')
            app.CentroidTab = uitab(app.TabGroup);
            app.CentroidTab.Title = 'Centroid ';
            app.CentroidPlot = uiaxes(app.CentroidTab);
            title(app.CentroidPlot, '')
            xlabel(app.CentroidPlot, 'X')
            ylabel(app.CentroidPlot, 'Y')
            app.FourierFitDataTab = uitab(app.TabGroup);
            app.FourierFitDataTab.Title = 'Fourier Fit Data';
            app.AsphericityPlot = uiaxes(app.FourierFitDataTab);
            title(app.AsphericityPlot, '')
            xlabel(app.AsphericityPlot, 'Frame')
            ylabel(app.AsphericityPlot, 'sqrt(aN^2 + bN^2) / sqrt(a1^2 + b1^2)')
            app.FourierColorMap = uiimage(app.FourierFitDataTab);
            app.ndTermLabel = uilabel(app.FourierFitDataTab);
            app.ndTermLabel.Text = '2nd Term';
            app.LastTermLabel = uilabel(app.FourierFitDataTab);
            app.LastTermLabel.Text = 'Last Term';
            app.FourierDecompositionTab = uitab(app.TabGroup);
            app.FourierDecompositionTab.Title = 'Fourier Decomposition';
            app.DecompositionSettingsPanel = uipanel(app.FourierDecompositionTab);
            app.DecompositionSettingsPanel.Title = 'Decomposition Settings';
            app.TargetFrameEditFieldLabel = uilabel(app.DecompositionSettingsPanel);
            app.TargetFrameEditFieldLabel.Text = 'Target Frame ';
            app.TargetFrameEditField = uieditfield(app.DecompositionSettingsPanel, 'numeric');
            app.TargetFrameEditField.Limits = [1 Inf];
            app.TargetFrameEditField.Value = 1;
            app.TermstoDecomposeEditFieldLabel = uilabel(app.DecompositionSettingsPanel);
            app.TermstoDecomposeEditFieldLabel.Text = 'Terms to Decompose';
            app.TermstoDecomposeEditField = uieditfield(app.DecompositionSettingsPanel, 'numeric');
            app.TermstoDecomposeEditField.Limits = [1 Inf];
            app.TermstoDecomposeEditField.Value = 8;
            app.SortOrderButtonGroup = uibuttongroup(app.DecompositionSettingsPanel);
            app.SortOrderButtonGroup.Title = 'Sort Order';
            app.DescendingButton = uiradiobutton(app.SortOrderButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DescendingButton.Value = true;
            app.AscendingButton = uiradiobutton(app.SortOrderButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.MaintainColormapCheckBox = uicheckbox(app.DecompositionSettingsPanel);
            app.MaintainColormapCheckBox.Text = 'Maintain Colormap';
            app.MaintainColormapCheckBox.Value = true;
            app.DecomposeButton = uibutton(app.DecompositionSettingsPanel, 'push');
            app.DecomposeButton.ButtonPushedFcn = createCallbackFcn(app, @DecomposeButtonPushed, true);
            app.DecomposeButton.Text = 'Decompose';
            app.DecomposedPlotsPanel = uipanel(app.FourierDecompositionTab);
            app.DecomposedPlotsPanel.Title = 'Decomposed Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end